classdef app_case < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        LeftPanel           matlab.ui.container.Panel
        Button_Save_2       matlab.ui.control.Button
        Lamp                matlab.ui.control.Lamp
        TextArea            matlab.ui.control.TextArea
        Label               matlab.ui.control.Label
        Panel_4             matlab.ui.container.Panel
        CheckBox_box        matlab.ui.control.CheckBox
        CheckBox_interfere  matlab.ui.control.CheckBox
        CheckBox_park       matlab.ui.control.CheckBox
        CheckBox_costmap    matlab.ui.control.CheckBox
        CheckBox_opt_path   matlab.ui.control.CheckBox
        CheckBox_path       matlab.ui.control.CheckBox
        CheckBox_obs        matlab.ui.control.CheckBox
        CheckBox_pose       matlab.ui.control.CheckBox
        PlotButton          matlab.ui.control.Button
        File_name           matlab.ui.control.TextArea
        Label_12            matlab.ui.control.Label
        Button_Save         matlab.ui.control.Button
        Panel               matlab.ui.container.Panel
        Button_swap         matlab.ui.control.Button
        theta0              matlab.ui.control.NumericEditField
        Label_4             matlab.ui.control.Label
        y0                  matlab.ui.control.NumericEditField
        y0EditField_2Label  matlab.ui.control.Label
        x0                  matlab.ui.control.NumericEditField
        x0EditField_2Label  matlab.ui.control.Label
        thetaf              matlab.ui.control.NumericEditField
        fLabel              matlab.ui.control.Label
        yf                  matlab.ui.control.NumericEditField
        yfEditFieldLabel    matlab.ui.control.Label
        xf                  matlab.ui.control.NumericEditField
        xfEditFieldLabel    matlab.ui.control.Label
        RightPanel          matlab.ui.container.Panel
        TabGroup            matlab.ui.container.TabGroup
        Tab_obs             matlab.ui.container.Tab
        ListBox_obs         matlab.ui.control.ListBox
        ListBoxLabel        matlab.ui.control.Label
        obs_theta           matlab.ui.control.NumericEditField
        Label_13            matlab.ui.control.Label
        obs_y               matlab.ui.control.NumericEditField
        yLabel              matlab.ui.control.Label
        obs_x               matlab.ui.control.NumericEditField
        xLabel              matlab.ui.control.Label
        obs_w               matlab.ui.control.NumericEditField
        WLabel              matlab.ui.control.Label
        obs_l               matlab.ui.control.NumericEditField
        LLabel              matlab.ui.control.Label
        Button_Del          matlab.ui.control.Button
        Button_Mod          matlab.ui.control.Button
        Button_Add          matlab.ui.control.Button
        Tab_park            matlab.ui.container.Tab
        park_theta          matlab.ui.control.NumericEditField
        Label_16            matlab.ui.control.Label
        park_y              matlab.ui.control.NumericEditField
        yLabel_2            matlab.ui.control.Label
        park_x              matlab.ui.control.NumericEditField
        xLabel_2            matlab.ui.control.Label
        park_w              matlab.ui.control.NumericEditField
        WLabel_2            matlab.ui.control.Label
        park_l              matlab.ui.control.NumericEditField
        LLabel_2            matlab.ui.control.Label
        Button_Del_park     matlab.ui.control.Button
        Button_Mod_park     matlab.ui.control.Button
        Button_Add_park     matlab.ui.control.Button
        ListBox_park        matlab.ui.control.ListBox
        Label_15            matlab.ui.control.Label
        Button_Debug        matlab.ui.control.Button
        Panel_3             matlab.ui.container.Panel
        DropDown_3          matlab.ui.control.DropDown
        Label_11            matlab.ui.control.Label
        DropDown_2          matlab.ui.control.DropDown
        Label_10            matlab.ui.control.Label
        DropDown_Interfere  matlab.ui.control.DropDown
        Label_9             matlab.ui.control.Label
        DropDown_cyn        matlab.ui.control.DropDown
        Label_8             matlab.ui.control.Label
        Label_7             matlab.ui.control.Label
        Label_5             matlab.ui.control.Label
        DropDown_Search     matlab.ui.control.DropDown
        Button_Motion       matlab.ui.control.Button
        Button_Interfere    matlab.ui.control.Button
        Button_Optimize     matlab.ui.control.Button
        Button_Search       matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% 开启软件，创建结构体
            global case_data plt params_
            params_ = Initialize();
            case_data = struct;
            
            %% 更新参数
            case_data.start_pose = [app.x0.Value,app.y0.Value,app.theta0.Value];
            case_data.goal_pose = [app.xf.Value,app.yf.Value,app.thetaf.Value];
            
            case_data.obstacle = {}; % 存储障碍物的空元胞数组
            case_data.parking = {}; % 存储停车位的空元胞数组
            case_data.has = struct(); % 存储混合 A star 的规划路径
            case_data.opt = struct(); % 存储优化后的轨迹
            
            %% 绘制参数
            plt = struct; % 根据复选框绘图要求绘制相应部分
            plt.pose = app.CheckBox_pose.Value;
            plt.obs = app.CheckBox_obs.Value;
            plt.path = app.CheckBox_path.Value;
            plt.opt_path = app.CheckBox_opt_path.Value;
            plt.park = app.CheckBox_park.Value;
            plt.interfere = 0;
            plt.motion = 0;
            plt.mark = [];
            plt.hold = 0;
            plt.box = app.CheckBox_box.Value;
            plt.ax = app.UIAxes;
            plt.costmap = 0;
            plt.run = "app";
            %% 设置画布
            axis(app.UIAxes, 'equal');
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            global case_data plt
            
            %% 重新设置起终点
            case_data.start_pose = [app.x0.Value,app.y0.Value,app.theta0.Value];
            case_data.goal_pose = [app.xf.Value,app.yf.Value,app.thetaf.Value];
            
            % 清空已有路径
            case_data.has = struct(); % 存储混合A star 的规划路径
            case_data.opt = struct(); % 存储优化后的轨迹
            
            %% 绘制场景
            plt.hold = 0;
            app_plot(case_data,plt);
            
        end

        % Button pushed function: Button_Add
        function Button_AddPushed(app, event)
            global case_data plt
            
            %创建障碍物结构体
            obs = struct;
            obs.x0 = app.obs_x.Value;
            obs.y0 = app.obs_y.Value;
            obs.theta0 = app.obs_theta.Value;
            obs.l = app.obs_l.Value;
            obs.w = app.obs_w.Value;
            %计算顶点
            O = obs_plot(obs);
            obs.x = O.x;
            obs.y = O.y;
            % 加入总数据结构体
            case_data.obstacle = [case_data.obstacle, obs];
            
            % 列表框加一个项目
            n = length(case_data.obstacle);
            app.ListBox_obs.Items = [app.ListBox_obs.Items, {num2str(n)}];
            % 障碍物列表框自动更新最新的
            app.ListBox_obs.Value = num2str(n);

            %消息框提示
            app.TextArea.Value = ['创建了障碍物：', num2str(n)];
            app_plot(case_data,plt);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {772, 772};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {182, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: ListBox_obs
        function ListBox_obsValueChanged(app, event)
            global case_data plt
            % 根据点击位置提取数据
            n = str2num(app.ListBox_obs.Value);
            obs = case_data.obstacle{n};
            % 文本窗预览
            app.TextArea.Value = ['obstacle item: ', num2str(n),char(13),...
                'x: ', num2str(obs.x0),char(13),...
                'y: ', num2str(obs.y0),char(13),...
                'θ: ', num2str(obs.theta0),char(13),...
                'L: ', num2str(obs.l),char(13),...
                'W: ', num2str(obs.w)];
            %                                   'p_x: ',string(p_x), char(13), ...
            %                                   'p_y: ',string(p_y)];
            % 编辑窗预览
            app.obs_x.Value = obs.x0;
            app.obs_y.Value = obs.y0;
            app.obs_theta.Value = obs.theta0;
            app.obs_l.Value = obs.l;
            app.obs_w.Value = obs.w;
            % 画图区标记预览
            plt.mark = [obs.x; obs.y];
            app_plot(case_data,plt);
            plt.mark = [];
            app.TextArea.Value = ['标记 obs : ',app.ListBox_obs.Value];
        end

        % Button pushed function: Button_Del
        function Button_DelPushed(app, event)
            % 删除点击位置障碍物
            global case_data plt
            if (~isempty(case_data.obstacle))
                % 当前选择序号 n
                n = str2num(app.ListBox_obs.Value);
                app.TextArea.Value = ['已删除障碍物：', app.ListBox_obs.Value];
                % 如果选择且要删除的是最后一个，就把当前选择改为前一个
                if (n == length(app.ListBox_obs.Items) && n > 1)
                    app.ListBox_obs.Value = num2str(n-1);
                end
                % 元胞移除障碍物结构体
                case_data.obstacle(n) = [];
                % 列表框移除障碍物序号
                app.ListBox_obs.Items = app.ListBox_obs.Items(1:length(app.ListBox_obs.Items)-1);
                app_plot(case_data,plt);
            else
                app.TextArea.Value = '无法删除 障碍物列表为空';
            end
            
        end

        % Button pushed function: Button_Save
        function Button_SavePushed(app, event)
            %% 保存case结构体
            global case_data;
            name = strcat('case/',app.File_name.Value{1});
            save(name, "case_data");
            app.TextArea.Value = ['已保存当前场景：',name];
        end

        % Button pushed function: Button_Debug
        function Button_DebugPushed(app, event)
            global case_data plt  params_
            assignin('base','app',app);
            assignin('base','case_data', case_data);
            assignin('base','plt', plt);
            assignin('base','params_', params_);
            app.TextArea.Value = 'Debug: 发送 case_data / APP 到工作区';
        end

        % Button pushed function: Button_Mod
        function Button_ModPushed(app, event)
            % 修改点击位置障碍物参数
            global case_data plt
            if (~isempty(case_data.obstacle))
                %提取
                new_obs = struct();
                new_obs.x0 = app.obs_x.Value;
                new_obs.y0 = app.obs_y.Value;
                new_obs.theta0 = app.obs_theta.Value;
                new_obs.l = app.obs_l.Value;
                new_obs.w = app.obs_w.Value;
                %计算顶点
                O = obs_plot(new_obs);
                new_obs.x = O.x;
                new_obs.y = O.y;
                % 修改
                n = str2num(app.ListBox_obs.Value);
                case_data.obstacle{n} = new_obs;
                app.TextArea.Value = ['已修改障碍物：', app.ListBox_obs.Value];
                app_plot(case_data,plt);
            else
                app.TextArea.Value = '无法修改 障碍物列表为空';
            end
        end

        % Button pushed function: Button_Search
        function Button_SearchPushed(app, event)
            %%  混合Astar 搜索路径
            app.TextArea.Value = '路径规划：当前场景';
            global case_data plt;
            
            Initialize();
            [~, ~, costmap] = Load_case(case_data);
            
            
            %%% 根据选择方法搜索
            has = struct;
            switch app.DropDown_Search.Value
                case 'Astar'
                    % A star
                    [has.x, has.y, has.theta] = SearchAStar(case_data.start_pose,case_data.goal_pose);
                case 'Has1'
                    % Hybrid A star /  matlab 自带
                    [has.x, has.y, has.theta,map] = SearchHybridAStar_1(case_data.start_pose,case_data.goal_pose,costmap);
                case 'Has2'
                    % Hybrid A star / libai
                    [has.x, has.y, has.theta,map] = SearchHybridAStar_2(case_data.start_pose,case_data.goal_pose,costmap);
                case 'RRTstar'
                    % RRT star
                    [has.x, has.y, has.theta,map] = SearchRRTStar(case_data.start_pose,case_data.goal_pose,costmap);
            end
            case_data.has = has; %搜索路径更新
            case_data.opt = struct; %优化路径清除
            
            
            app.TextArea.Value = '路径规划：已完成';
            % 绘制
            plt.path = 1;
            app_plot(case_data,plt);
        end

        % Button pushed function: Button_Optimize
        function Button_OptimizePushed(app, event)
            % 优化路径
            global case_data plt;
            
            plt.box = app.CheckBox_box.Value;
            if plt.box
                plt.hold = 1;
            end
            app_plot(case_data,plt);
            app.TextArea.Value = '路径优化：优化中';
            
            [good, opt] = OptiMethod(case_data.has,plt);
            if good == 0
                app.TextArea.Value = '路径优化：失败';
            else
                app.TextArea.Value = '路径优化：已完成';
            end
            
            case_data.opt = opt;
        end

        % Button pushed function: Button_Interfere
        function Button_InterferePushed(app, event)
            global case_data plt;
            app.TextArea.Value = '干涉演示：绘制';
            plt.interfere = 1;
            app.CheckBox_interfere.Value = 1;
            %% 绘制干涉场景
            gs = [str2num(app.DropDown_Interfere.Value),str2num(app.DropDown_cyn.Value)];% app.DropDown_cyn 采样点个数

            app_plot(case_data,plt,gs);
            plt.interfere = 0;
        end

        % Value changed function: DropDown_cyn
        function DropDown_cynValueChanged(app, event)
            value = app.DropDown_cyn.Value;
            
        end

        % Button pushed function: Button_swap
        function Button_swapPushed(app, event)
            global case_data
            
            xt = case_data.start_pose;
            case_data.start_pose = case_data.goal_pose;
            case_data.goal_pose = xt;
            % 起末点交换
            x = app.x0.Value;
            y = app.y0.Value;
            t = app.theta0.Value;
            app.x0.Value = app.xf.Value;
            app.y0.Value = app.yf.Value;
            app.theta0.Value = app.thetaf.Value;
            app.xf.Value = x;
            app.yf.Value = y;
            app.thetaf.Value = t;
        end

        % Button pushed function: Button_Add_park
        function Button_Add_parkPushed(app, event)
            global case_data plt
            
            %创建停车位结构体
            new_park = struct;
            new_park.x0 = app.park_x.Value;
            new_park.y0 = app.park_y.Value;
            new_park.theta0 = app.park_theta.Value;
            new_park.l = app.park_l.Value;
            new_park.w = app.park_w.Value;
            %计算顶点
            O = obs_plot(new_park);
            new_park.x = O.x;
            new_park.y = O.y;
            % 加入总数据结构体
            case_data.parking = [case_data.parking, new_park];
            
            % 列表框加一个项目
            n = length(case_data.parking);
            app.ListBox_park.Items = [app.ListBox_park.Items, {num2str(n)}];
            % 停车位列表框自动更新最新的
            app.ListBox_park.Value = num2str(n);

            %消息框提示
            app.TextArea.Value = ['创建了一个停车位：', num2str(n)];
            app_plot(case_data,plt);
        end

        % Button pushed function: Button_Mod_park
        function Button_Mod_parkPushed(app, event)
            % 修改点击位置停车位参数
            global case_data plt
            if (~isempty(case_data.parking))
                %创建停车位结构体
                new_park = struct;
                new_park.x0 = app.park_x.Value;
                new_park.y0 = app.park_y.Value;
                new_park.theta0 = app.park_theta.Value;
                new_park.l = app.park_l.Value;
                new_park.w = app.park_w.Value;
                %计算顶点
                O = obs_plot(new_park);
                new_park.x = O.x;
                new_park.y = O.y;
                % 修改
                n = str2num(app.ListBox_park.Value);
                case_data.parking{n} = new_park;
                app.TextArea.Value = ['已修改停车位：', app.ListBox_park.Value];
                app_plot(case_data,plt);
            else
                app.TextArea.Value = '无法修改 停车位列表为空';
            end
        end

        % Button pushed function: Button_Del_park
        function Button_Del_parkPushed(app, event)
            % 删除点击位置停车位
            global case_data
            if (~isempty(case_data.parking))
                % 当前选择序号 n
                n = str2num(app.ListBox_park.Value);
                app.TextArea.Value = ['已删除停车位：', app.ListBox_park.Value];
                % 如果选择且要删除的是最后一个，就把当前选择改为前一个
                if (n == length(app.ListBox_park.Items) && n > 1)
                    app.ListBox_park.Value = num2str(n-1);
                end
                % 元胞移除停车位结构体
                case_data.parking(n) = [];
                % 列表框移除停车位序号
                app.ListBox_park.Items = app.ListBox_park.Items(1:length(app.ListBox_park.Items)-1);
                app_plot(case_data,plt);
            else
                app.TextArea.Value = '无法删除 停车位列表为空';
            end
        end

        % Button pushed function: Button_Save_2
        function Button_Save_2Pushed(app, event)
            % 打开文件选择框
            global case_data plt
            [file,~,~] = uigetfile('*.mat');
            if isequal(file,0)
                app.TextArea.Value = 'User selected Cancel';
            else
                app.TextArea.Value = ['User selected: ', file];
                name = strcat('case/',file);
                load(name);
                % 起末点 更新参数
                app.x0.Value = case_data.start_pose(1); % 起点
                app.y0.Value = case_data.start_pose(2);
                app.theta0.Value = case_data.start_pose(3);
                app.xf.Value = case_data.goal_pose(1); % 终点
                app.yf.Value = case_data.goal_pose(2);
                app.thetaf.Value = case_data.goal_pose(3);

                
                % 障碍物和停车位列表框添加对应的项目编号
                n_obs = length(case_data.obstacle);
                n_park = length(case_data.parking);
                if n_obs > 0
                    app.ListBox_obs.Items = app.ListBox_obs.Items(1:0); % 先清空
                    for i=1:n_obs
                        app.ListBox_obs.Items = [app.ListBox_obs.Items, {num2str(i)}];
                    end
                end
                if n_park > 0
                    app.ListBox_park.Items = app.ListBox_park.Items(1:0); % 先清空
                    for i=1:n_park
                        app.ListBox_park.Items = [app.ListBox_park.Items, {num2str(i)}];
                    end
                end
                app_plot(case_data,plt);
            end
            
        end

        % Value changed function: ListBox_park
        function ListBox_parkValueChanged(app, event)
            global case_data plt
            % 根据点击位置提取数据
            n = str2num(app.ListBox_park.Value);
            park = case_data.parking{n};
            % 文本窗预览
            app.TextArea.Value = ['parking space item: ', num2str(n),char(13),...
                'x: ', num2str(park.x0),char(13),...
                'y: ', num2str(park.y0),char(13),...
                'θ: ', num2str(park.theta0),char(13),...
                'L: ', num2str(park.l),char(13),...
                'W: ', num2str(park.w)];
            % 编辑窗预览
            app.park_x.Value = park.x0;
            app.park_y.Value = park.y0;
            app.park_theta.Value = park.theta0;
            app.park_l.Value = park.l;
            app.park_w.Value = park.w;
            % 画图区标记预览
            plt.mark = [park.x; park.y];
            app_plot(case_data,plt);
            plt.mark = [];
            app.TextArea.Value = ['标记 park : ',app.ListBox_obs.Value];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '自动泊车场景创建工具';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.LeftPanel);
            app.Panel.Title = '起终点编辑';
            app.xfEditFieldLabel = uilabel(app.Panel);
            app.xfEditFieldLabel.Text = 'xf';
            app.xf = uieditfield(app.Panel, 'numeric');
            app.xf.Value = 15;
            app.yfEditFieldLabel = uilabel(app.Panel);
            app.yfEditFieldLabel.Text = 'yf';
            app.yf = uieditfield(app.Panel, 'numeric');
            app.yf.Value = 6;
            app.fLabel = uilabel(app.Panel);
            app.fLabel.Text = 'θf';
            app.thetaf = uieditfield(app.Panel, 'numeric');
            app.thetaf.Value = 1.57;
            app.x0EditField_2Label = uilabel(app.Panel);
            app.x0EditField_2Label.Text = 'x0';
            app.x0 = uieditfield(app.Panel, 'numeric');
            app.y0EditField_2Label = uilabel(app.Panel);
            app.y0EditField_2Label.Text = 'y0';
            app.y0 = uieditfield(app.Panel, 'numeric');
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = 'θ0';
            app.theta0 = uieditfield(app.Panel, 'numeric');
            app.Button_swap = uibutton(app.Panel, 'push');
            app.Button_swap.ButtonPushedFcn = createCallbackFcn(app, @Button_swapPushed, true);
            app.Button_swap.Text = '互换';
            app.Button_Save = uibutton(app.LeftPanel, 'push');
            app.Button_Save.ButtonPushedFcn = createCallbackFcn(app, @Button_SavePushed, true);
            app.Button_Save.Text = 'Save';
            app.Label_12 = uilabel(app.LeftPanel);
            app.Label_12.Text = '文件名';
            app.File_name = uitextarea(app.LeftPanel);
            app.File_name.Value = {'case.mat'};
            app.Panel_4 = uipanel(app.LeftPanel);
            app.Panel_4.Title = '绘图区';
            app.PlotButton = uibutton(app.Panel_4, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.CheckBox_pose = uicheckbox(app.Panel_4);
            app.CheckBox_pose.Text = '起末位置';
            app.CheckBox_pose.Value = true;
            app.CheckBox_obs = uicheckbox(app.Panel_4);
            app.CheckBox_obs.Text = '障碍物';
            app.CheckBox_obs.Value = true;
            app.CheckBox_path = uicheckbox(app.Panel_4);
            app.CheckBox_path.Text = '粗轨迹';
            app.CheckBox_path.Value = true;
            app.CheckBox_opt_path = uicheckbox(app.Panel_4);
            app.CheckBox_opt_path.Text = '最优轨迹';
            app.CheckBox_opt_path.Value = true;
            app.CheckBox_costmap = uicheckbox(app.Panel_4);
            app.CheckBox_costmap.Text = 'Costmap';
            app.CheckBox_park = uicheckbox(app.Panel_4);
            app.CheckBox_park.Text = '车位';
            app.CheckBox_park.Value = true;
            app.CheckBox_interfere = uicheckbox(app.Panel_4);
            app.CheckBox_interfere.Text = '干涉图';
            app.CheckBox_box = uicheckbox(app.Panel_4);
            app.CheckBox_box.Text = 'BOX';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '操作消息框';
            app.TextArea = uitextarea(app.LeftPanel);
            app.TextArea.Value = {'无消息'};
            app.Lamp = uilamp(app.LeftPanel);
            app.Button_Save_2 = uibutton(app.LeftPanel, 'push');
            app.Button_Save_2.ButtonPushedFcn = createCallbackFcn(app, @Button_Save_2Pushed, true);
            app.Button_Save_2.Text = 'Load';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, '场景预览')
            app.Panel_3 = uipanel(app.RightPanel);
            app.Panel_3.Title = '操作区';
            app.Button_Search = uibutton(app.Panel_3, 'push');
            app.Button_Search.ButtonPushedFcn = createCallbackFcn(app, @Button_SearchPushed, true);
            app.Button_Search.Text = '搜索路径';
            app.Button_Optimize = uibutton(app.Panel_3, 'push');
            app.Button_Optimize.ButtonPushedFcn = createCallbackFcn(app, @Button_OptimizePushed, true);
            app.Button_Optimize.Text = '轨迹优化';
            app.Button_Interfere = uibutton(app.Panel_3, 'push');
            app.Button_Interfere.ButtonPushedFcn = createCallbackFcn(app, @Button_InterferePushed, true);
            app.Button_Interfere.Text = '干涉演示';
            app.Button_Motion = uibutton(app.Panel_3, 'push');
            app.Button_Motion.Text = '运动演示';
            app.DropDown_Search = uidropdown(app.Panel_3);
            app.DropDown_Search.Items = {'Has1', 'Has2', 'Astar', 'Curve', 'RRTstar'};
            app.DropDown_Search.Value = 'Has1';
            app.Label_5 = uilabel(app.Panel_3);
            app.Label_5.Text = '方法:';
            app.Label_7 = uilabel(app.Panel_3);
            app.Label_7.Text = '';
            app.Label_8 = uilabel(app.Panel_3);
            app.Label_8.Text = '采样:';
            app.DropDown_cyn = uidropdown(app.Panel_3);
            app.DropDown_cyn.Items = {'1', '3', '5', '7', '10', '15', '20', '25'};
            app.DropDown_cyn.ValueChangedFcn = createCallbackFcn(app, @DropDown_cynValueChanged, true);
            app.DropDown_cyn.Value = '10';
            app.Label_9 = uilabel(app.Panel_3);
            app.Label_9.Text = '轨迹:';
            app.DropDown_Interfere = uidropdown(app.Panel_3);
            app.DropDown_Interfere.Items = {'1', '2'};
            app.DropDown_Interfere.Value = '1';
            app.Label_10 = uilabel(app.Panel_3);
            app.Label_10.Text = '轨迹点:';
            app.DropDown_2 = uidropdown(app.Panel_3);
            app.DropDown_2.Items = {'10', '50', '100', '200', '500', ''};
            app.DropDown_2.Value = '100';
            app.Label_11 = uilabel(app.Panel_3);
            app.Label_11.Text = '倍速:';
            app.DropDown_3 = uidropdown(app.Panel_3);
            app.DropDown_3.Items = {'1', '3', '5', '7', '10'};
            app.DropDown_3.Value = '1';
            app.Button_Debug = uibutton(app.RightPanel, 'push');
            app.Button_Debug.ButtonPushedFcn = createCallbackFcn(app, @Button_DebugPushed, true);
            app.Button_Debug.Text = 'Debug';
            app.TabGroup = uitabgroup(app.RightPanel);
            app.Tab_obs = uitab(app.TabGroup);
            app.Tab_obs.Title = '障碍物';
            app.Button_Add = uibutton(app.Tab_obs, 'push');
            app.Button_Add.ButtonPushedFcn = createCallbackFcn(app, @Button_AddPushed, true);
            app.Button_Add.Text = 'Add';
            app.Button_Mod = uibutton(app.Tab_obs, 'push');
            app.Button_Mod.ButtonPushedFcn = createCallbackFcn(app, @Button_ModPushed, true);
            app.Button_Mod.Text = {'Mod'; ''};
            app.Button_Del = uibutton(app.Tab_obs, 'push');
            app.Button_Del.ButtonPushedFcn = createCallbackFcn(app, @Button_DelPushed, true);
            app.Button_Del.Text = 'Del';
            app.LLabel = uilabel(app.Tab_obs);
            app.LLabel.Text = 'L';
            app.obs_l = uieditfield(app.Tab_obs, 'numeric');
            app.obs_l.Value = 1;
            app.WLabel = uilabel(app.Tab_obs);
            app.WLabel.Text = 'W';
            app.obs_w = uieditfield(app.Tab_obs, 'numeric');
            app.obs_w.Value = 1;
            app.xLabel = uilabel(app.Tab_obs);
            app.xLabel.Text = 'x';
            app.obs_x = uieditfield(app.Tab_obs, 'numeric');
            app.obs_x.Value = 14;
            app.yLabel = uilabel(app.Tab_obs);
            app.yLabel.Text = 'y';
            app.obs_y = uieditfield(app.Tab_obs, 'numeric');
            app.obs_y.Value = 5;
            app.Label_13 = uilabel(app.Tab_obs);
            app.Label_13.Text = 'θ';
            app.obs_theta = uieditfield(app.Tab_obs, 'numeric');
            app.ListBoxLabel = uilabel(app.Tab_obs);
            app.ListBoxLabel.Text = '障碍列表';
            app.ListBox_obs = uilistbox(app.Tab_obs);
            app.ListBox_obs.Items = {};
            app.ListBox_obs.ValueChangedFcn = createCallbackFcn(app, @ListBox_obsValueChanged, true);
            app.ListBox_obs.Value = {};
            app.Tab_park = uitab(app.TabGroup);
            app.Tab_park.Title = '车位';
            app.Label_15 = uilabel(app.Tab_park);
            app.Label_15.Text = '车位列表';
            app.ListBox_park = uilistbox(app.Tab_park);
            app.ListBox_park.Items = {};
            app.ListBox_park.ValueChangedFcn = createCallbackFcn(app, @ListBox_parkValueChanged, true);
            app.ListBox_park.Value = {};
            app.Button_Add_park = uibutton(app.Tab_park, 'push');
            app.Button_Add_park.ButtonPushedFcn = createCallbackFcn(app, @Button_Add_parkPushed, true);
            app.Button_Add_park.Text = 'Add';
            app.Button_Mod_park = uibutton(app.Tab_park, 'push');
            app.Button_Mod_park.ButtonPushedFcn = createCallbackFcn(app, @Button_Mod_parkPushed, true);
            app.Button_Mod_park.Text = {'Mod'; ''};
            app.Button_Del_park = uibutton(app.Tab_park, 'push');
            app.Button_Del_park.ButtonPushedFcn = createCallbackFcn(app, @Button_Del_parkPushed, true);
            app.Button_Del_park.Text = 'Del';
            app.LLabel_2 = uilabel(app.Tab_park);
            app.LLabel_2.Text = 'L';
            app.park_l = uieditfield(app.Tab_park, 'numeric');
            app.park_l.Value = 6;
            app.WLabel_2 = uilabel(app.Tab_park);
            app.WLabel_2.Text = 'W';
            app.park_w = uieditfield(app.Tab_park, 'numeric');
            app.park_w.Value = 2.4;
            app.xLabel_2 = uilabel(app.Tab_park);
            app.xLabel_2.Text = 'x';
            app.park_x = uieditfield(app.Tab_park, 'numeric');
            app.park_x.Value = 14;
            app.yLabel_2 = uilabel(app.Tab_park);
            app.yLabel_2.Text = 'y';
            app.park_y = uieditfield(app.Tab_park, 'numeric');
            app.park_y.Value = 5;
            app.Label_16 = uilabel(app.Tab_park);
            app.Label_16.Text = 'θ';
            app.park_theta = uieditfield(app.Tab_park, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_case

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end